classdef appRetoFlujoFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
        A2cm2EditField       matlab.ui.control.NumericEditField
        A2cm2EditFieldLabel  matlab.ui.control.Label
        A1cm2EditField       matlab.ui.control.NumericEditField
        A1cm2EditFieldLabel  matlab.ui.control.Label
        hocmEditField        matlab.ui.control.NumericEditField
        hocmEditFieldLabel   matlab.ui.control.Label
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        yo=app.hocmEditField.Value;
        A1=app.A1cm2EditField.Value;%%Area del circulo superior
        A2=app.A2cm2EditField.Value;%%Area del hoyo
        g=980; %Gravedad en cm/s
        tf=2*sqrt(yo*(A1^2-A2^2)/(2*g*A2^2));
        t=0:0.01:tf;
        h=(sqrt(yo)-0.5*sqrt(2*g*A2^2/(A1^2-A2^2))*t).^2;
        v1=sqrt(2*g*A2^2*h/(A1^2-A2^2));
        G=A1*v1;
        v2=G/A2;
        plot(app.UIAxes2,t,h,"b");hold(app.UIAxes2,"on");
        plot(app.UIAxes,t,G,"b");hold(app.UIAxes,"on");
        plot(app.UIAxes4,t,v1,"b",t,v2,"r");hold(app.UIAxes4,"on");


        for t=0:1:tf
        h=(sqrt(yo)-0.5*sqrt(2*g*A2^2/(A1^2-A2^2))*t).^2;
        v1=sqrt(2*g*A2^2*h/(A1^2-A2^2));
        G=A1*v1;
        v2=G/A2;
        fuga=(yo-h).*A1.*A2;
        plot(app.UIAxes2,t,h,"b o");
        plot(app.UIAxes,t,G,"b o");
        plot(app.UIAxes4,t,v1,"b o",t,v2,"r o");
        plot(app.UIAxes5, t,fuga,"b o"); 
        bar(app.UIAxes3,t,h,"b"); ylim(app.UIAxes3,[0,yo]);
        pause(1);
        
        plot(app.UIAxes2,t,h,"w o");
        plot(app.UIAxes,t,G,"w o");
        plot(app.UIAxes4,t,v1,"w o",t,v2,"w o");
        plot(app.UIAxes5, t,fuga,"w o");
        end
        %plot(app.UIAxes5, t,fuga,"b"); hold(app.UIAxes5,"on");


        end

        % Value changed function: A1cm2EditField
        function A1cm2EditFieldValueChanged(app, event)
            value = app.A1cm2EditField.Value;
            
        end

        % Value changed function: hocmEditField
        function hocmEditFieldValueChanged(app, event)
            value = app.hocmEditField.Value;
            
        end

        % Value changed function: A2cm2EditField
        function A2cm2EditFieldValueChanged(app, event)
            value = app.A2cm2EditField.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");
            hold(app.UIAxes4,"off");
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Flujo')
            xlabel(app.UIAxes, 't(s)')
            ylabel(app.UIAxes, 'G(cm^3/s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Nivel del tanque')
            xlabel(app.UIAxes2, 't(s)')
            ylabel(app.UIAxes2, 'h(cm)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Nivel del tanque')
            xlabel(app.UIAxes3, 's')
            ylabel(app.UIAxes3, 'h(cm)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Velocidad del nivel y salida')
            xlabel(app.UIAxes4, 't(s)')
            ylabel(app.UIAxes4, 'cm/s')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Descarga')
            xlabel(app.UIAxes5, 'S')
            ylabel(app.UIAxes5, 'ml')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.hocmEditFieldLabel = uilabel(app.UIFigure);
            app.hocmEditFieldLabel.Text = 'ho(cm)';
            app.hocmEditField = uieditfield(app.UIFigure, 'numeric');
            app.hocmEditField.ValueChangedFcn = createCallbackFcn(app, @hocmEditFieldValueChanged, true);
            app.hocmEditField.Value = 13;
            app.A1cm2EditFieldLabel = uilabel(app.UIFigure);
            app.A1cm2EditFieldLabel.Text = 'A1(cm^2)';
            app.A1cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.A1cm2EditField.ValueChangedFcn = createCallbackFcn(app, @A1cm2EditFieldValueChanged, true);
            app.A1cm2EditField.Value = 100;
            app.A2cm2EditFieldLabel = uilabel(app.UIFigure);
            app.A2cm2EditFieldLabel.Text = 'A2(cm^2)';
            app.A2cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.A2cm2EditField.ValueChangedFcn = createCallbackFcn(app, @A2cm2EditFieldValueChanged, true);
            app.A2cm2EditField.Value = 0.5;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRetoFlujoFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end